classdef aospregui < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        SimulationDetailsTab           matlab.ui.container.Tab
        FileTimePatternEditField       matlab.ui.control.EditField
        FileTimePatternEditFieldLabel  matlab.ui.control.Label
        PlottedVariableDropDown        matlab.ui.control.DropDown
        PlottedVariableDropDownLabel   matlab.ui.control.Label
        FileSearchFeedback             matlab.ui.control.Label
        FileSearchPatternEditField     matlab.ui.control.EditField
        FileSearchPatternEditFieldLabel  matlab.ui.control.Label
        AOSPREDetailsTab               matlab.ui.container.Tab
        PanelandScanDetails            matlab.ui.container.Panel
        GridLayout                     matlab.ui.container.GridLayout
        MaximumTiltRotEditField        matlab.ui.control.EditField
        MaximumTiltRotEditFieldLabel   matlab.ui.control.Label
        AftEditField                   matlab.ui.control.EditField
        AftEditFieldLabel              matlab.ui.control.Label
        ForeEditField_2                matlab.ui.control.EditField
        ForeEditField_2Label           matlab.ui.control.Label
        angularresolutionEditField     matlab.ui.control.EditField
        angularresolutionEditFieldLabel  matlab.ui.control.Label
        Tree                           matlab.ui.container.CheckBoxTree
        LHSPortPanelNode               matlab.ui.container.TreeNode
        LHSRHI                         matlab.ui.container.TreeNode
        LHSPPI                         matlab.ui.container.TreeNode
        LHSFore                        matlab.ui.container.TreeNode
        AftScanNode                    matlab.ui.container.TreeNode
        RHSStarboardPanelNode          matlab.ui.container.TreeNode
        RHSRHI                         matlab.ui.container.TreeNode
        RHSPPI                         matlab.ui.container.TreeNode
        RHSFore                        matlab.ui.container.TreeNode
        TopPanelNode                   matlab.ui.container.TreeNode
        TOPRHI                         matlab.ui.container.TreeNode
        TOPPPI                         matlab.ui.container.TreeNode
        TOPFore                        matlab.ui.container.TreeNode
        BottomPanelNode                matlab.ui.container.TreeNode
        BOTRHI                         matlab.ui.container.TreeNode
        BOTPPI                         matlab.ui.container.TreeNode
        BOTFore                        matlab.ui.container.TreeNode
        AircraftVelmsEditField         matlab.ui.control.NumericEditField
        AircraftVelmsEditFieldLabel    matlab.ui.control.Label
        StartTimeEditField             matlab.ui.control.NumericEditField
        StartTimeEditFieldLabel        matlab.ui.control.Label
        UITable                        matlab.ui.control.Table
        PlotRouteButton                matlab.ui.control.Button
        StartTimeSlider                matlab.ui.control.RangeSlider
        PlotDetailsPanel               matlab.ui.container.Panel
        SimResolutionLabel             matlab.ui.control.Label
        SimulationResolutionEditField  matlab.ui.control.NumericEditField
        SimulationLevelSlider          matlab.ui.control.Slider
        SimLevelLabel                  matlab.ui.control.Label
        SimulationLevelEditField       matlab.ui.control.NumericEditField
        TimeIndexSlider                matlab.ui.control.Slider
        TimeIndexLabel                 matlab.ui.control.Label
        TimeIndexEditField             matlab.ui.control.NumericEditField
        UIAxes                         matlab.ui.control.UIAxes
        ContextMenu                    matlab.ui.container.ContextMenu
        ClearMenu                      matlab.ui.container.Menu
        ClearSelection                 matlab.ui.container.Menu
        LegRowMenu                     matlab.ui.container.Menu
        RefreshMenu                    matlab.ui.container.Menu
        RefreshSelectionMenu           matlab.ui.container.Menu
        AddnewLegMenu                  matlab.ui.container.Menu
    end

    
    properties (Access = private)
        FileList % list of filenames from dir() call.
        FileTimes % times (in MATLAB durations) of the FileList files.
        PanelLocations % structure of Rotation/tilt for each panel
    end
    
    methods (Access = private)
        
        function [] = UpdateFigure(app)
            % function to redraw plan view


            var = ncread(...
                [...            % load variable at appropriate time
                    app.FileList(app.TimeIndexEditField.Value).folder, '/', ...
                    app.FileList(app.TimeIndexEditField.Value).name ...
                ], ...
                app.PlottedVariableDropDown.Value ...   %variable to load
            );
            
            try
                unitsTemp = ncreadatt(...
                    [...            % load variable at appropriate time
                        app.FileList(tIdx).folder, '/', ...
                        app.FileList(tIdx).name ...
                    ], ...
                    app.PlottedVariableDropDown.Value, 'units')
            end
            varSlice = var(:,:,app.SimulationLevelEditField.Value);
            p = pcolor(app.UIAxes, varSlice'); % transpose to fix WRF/MATLAB row/column difference
            p.EdgeColor = 'None';
            colorbar(app.UIAxes)
            clim(app.UIAxes, [prctile(varSlice(:), 10), prctile(varSlice(:), 90)+1e-3])
        end
        
        function [] = ChildrenLevel2Visibility(app)
            app.PlottedVariableDropDownLabel.Visible = "on";
            app.SimulationLevelSlider.Visible = "on";
            app.SimLevelLabel.Visible = "on";
            app.SimulationLevelEditField.Visible = "on";
            app.TimeIndexEditField.Visible = "on";
            app.TimeIndexLabel.Visible = "on";
            app.TimeIndexSlider.Visible = "on";
            app.PlottedVariableDropDown.Visible = "on";
            app.SimResolutionLabel.Visible = "on";
            app.SimulationResolutionEditField.Visible = "on";
            
        end
        
        function [] = UpdateFlightPath(app)
            if ~isempty(app.UITable.Data)
                
                x = app.UITable.Data(:,[1,3]);
                x = x(:);
                y = app.UITable.Data(:,[2,4]);
                y = y(:);
                hold(app.UIAxes, 'off')
                UpdateFigure(app);
                hold(app.UIAxes, 'on')
                scatter(app.UIAxes, x, y,'r', 'filled')
                
                
                plot(app.UIAxes, x, y, 'r', 'LineWidth',1.5)
            end
        end
        
        function [] = UpdateSliders(app)
            % set visibility (in case of failure above)
            
    
            %% set simulation level 
            % get temporary variable to set number of levels/times
            tempVar = ncread(...
                [...    %filename
                    app.FileList(1).folder,'/', ...
                    app.FileList(1).name...
                ], ...  %variable name
                app.PlottedVariableDropDown.Value ...
            );

            % get levels/times
            verticalLevels = size(tempVar,3);
            timeIndices = numel(app.FileList);
            
            % set ranges and temp values for sliders/edit fields
            app.TimeIndexEditField.Value = 1;
            app.TimeIndexSlider.Limits = [1, timeIndices];
            app.TimeIndexSlider.MinorTicks = 1:timeIndices;
            app.TimeIndexSlider.MajorTicks = intersect(round(linspace(1, timeIndices, sqrt(timeIndices))), app.TimeIndexSlider.MinorTicks);
            app.TimeIndexSlider.Value = 1;
            

            [~,bestLevelGuess] = max(squeeze(std(std(tempVar))));
            app.SimulationLevelEditField.Value = bestLevelGuess;
            app.SimulationLevelSlider.Value = bestLevelGuess;
            app.SimulationLevelSlider.Limits = [1, verticalLevels];
            app.SimulationLevelSlider.MinorTicks = 1:verticalLevels;
            app.SimulationLevelSlider.MajorTicks = intersect(round(linspace(1, verticalLevels, sqrt(verticalLevels))), app.SimulationLevelSlider.MinorTicks);
        
        end
        
        function [] = UpdateTable(app)
            % try to update the tables based on some simple logic
            TAB = app.UITable.Data;
            for iRow = 1:size(app.UITable.Data,1)
                try
                    % set start of next leg to end of last leg
                    if iRow >1 & nnz(isnan(TAB(iRow, 1:2)))==2
                        TAB(iRow, 1) = TAB(iRow-1, 3);
                        TAB(iRow, 2) = TAB(iRow-1, 4);
                    end

                    %if heading, start, and time are defined, and end
                    %locations are nan, update end location.
                    if nnz(~isnan(TAB(iRow, [1,2,5,6])))==4 & nnz(isnan(TAB(iRow, [3,4])))== 2
                        TAB(iRow, 3) = round(...
                            TAB(iRow, 1) + ...
                            cosd(TAB(iRow, 6)) * TAB(iRow, 5) * ...
                            app.AircraftVelmsEditField.Value/app.SimulationResolutionEditField.Value...
                        );
                        app.SimulationResolutionEditField.Value
                        
                        TAB(iRow, 4) = round(...
                            TAB(iRow, 2) + ...
                            sind(TAB(iRow, 6)) * TAB(iRow, 5) * ...
                            app.AircraftVelmsEditField.Value/app.SimulationResolutionEditField.Value...
                        );
                    elseif nnz(isnan(TAB(iRow, 5:6))) == 2
                    
                        DX = TAB(iRow, 3) - TAB(iRow,1);
                        DY = TAB(iRow, 4) - TAB(iRow,2);
                        DT = ... % leg time calculation
                            sqrt(DX^2 + DY^2) * app.SimulationResolutionEditField.Value / ...
                            app.AircraftVelmsEditField.Value ...
                        ;
                        TAB(iRow, 5) = DT;

                        HEADING = atan2d(DY, DX);
                        TAB(iRow, 6) = HEADING;
                        
                    end
                    
                    % heading calculation (math convention)
                    

                    % write to the table
                    app.UITable.Data = TAB;
                catch ME
                    ME.message
                    
                end
            end
        end
        
        function results = ClearTableSelction(app)

            
        end
        
        function results = GenerateScanTables(app, type)
            % range-height indicator
            RHI.TILT = -app.MaximumTiltRotEditField.Value:app.angularresolutionEditField.Value:app.MaximumTiltRotEditField.Value;
            RHI.ROT = zeros(size(RHI.TILT));

            % plan position/sector scan
            PPI.ROT = -app.MaximumTiltRotEditField.Value:app.angularresolutionEditField.Value:app.MaximumTiltRotEditField.Value;
            RHI.TILT = zeros(size(PPI.ROT));

            Fore.ROT = 'asdf'


        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % set environment variables
            app.PanelLocations.LHS.ROT = 270;
            app.PanelLocations.LHS.TILT = 0; 

            app.PanelLocations.RHS.ROT = 90;
            app.PanelLocations.RHS.TILT = 0;

            app.PanelLocations.TOP.ROT = 0;
            app.PanelLocations.TOP.TILT = 0;

            app.PanelLocations.TOP.ROT = 180;
            app.PanelLocations.TOP.TILT = 0;


            %! try to load last state of application
            if exist(".aospregui.log","file")
                FID = fopen('.aospregui.log');
                app.FileSearchPatternEditField.Value = fgetl(FID);
                app.FileSearchPatternEditFieldValueChanged(app)
                
                app.FileTimePatternEditField.Value = fgetl(FID);
                app.FileTimePatternEditFieldValueChanged(app)

                app.SimulationResolutionEditField.Value = str2double(fgetl(FID));
                

            end
        end

        % Value changed function: StartTimeEditField
        function StartTimeEditFieldValueChanged(app, event)
            value = app.StartTimeEditField.Value;
            app.StartTimeSlider.Value = value;
        end

        % Value changed function: FileSearchPatternEditField
        function FileSearchPatternEditFieldValueChanged(app, event)
            value = app.FileSearchPatternEditField.Value;
            try
                
                app.FileList = dir(string(value));
                app.FileList = app.FileList(~ismember({app.FileList.name},{'.','..'}));


                if numel(app.FileList)>0
                    app.FileSearchFeedback.Text = num2str(numel(app.FileList))+" files found.";
    
                
                    %% update variable dropdown based on file search
                    % get variables in file
                    
                    simInfo=ncinfo([app.FileList(1).folder,'/',app.FileList(1).name],"/");
                    varNames = string({simInfo.Variables.Name});
                    app.PlottedVariableDropDown.Items = varNames;
                    
                    %% Set children visibility
                    ChildrenLevel2Visibility(app)

                    UpdateSliders(app)

                    %% Try variable    
                    try
                        app.PlottedVariableDropDown.Value = "W";
                        app.UpdateSliders(app)
                        PlottedVariableDropDownValueChanged(app)
                    end

                    %% Guess simulation spatial resolution
                    try
                       XLAT = ncread(...
                           [...
                               app.FileList(1).folder, '/', ...
                               app.FileList(1).name ...
                           ], ...
                           'XLAT' ...
                       );

                       XLONG = ncread(...
                           [...
                               app.FileList(1).folder, '/', ...
                               app.FileList(1).name ...
                           ], ...
                           'XLONG' ...
                       );

                       commonResolutions = [50, 100, 250, 500, 1000]; % m
                       dlat = XLAT(2,2,1)-XLAT(1,1,1);
                       %dlong = XLONG(1,2)-XLONG(1,1);
                       dy = dlat*111131.745
                       
                       [~,bestGuessResolutionIdx] = min(abs(dy-commonResolutions));
                       bestGuessResolution = commonResolutions(bestGuessResolutionIdx);
                       app.SimulationResolutionEditField.Value = bestGuessResolution;
                    end

                    %% Guess time filename pattern
                    try
                        app.FileTimePatternEditField(app)
                    end

                    %% Update figure MMMM d, yyyy HH:mm:ss
                    UpdateFigure(app)


                else
                    app.FileSearchFeedback.Text = "Choose directory with at least 2 files.";
                end
            catch ME
                ME.message
                keyboard
                app.FileSearchFeedback.Text = {'Search Failed, try again.', char(ME.message)};
            end

        end

        % Value changed function: PlottedVariableDropDown
        function PlottedVariableDropDownValueChanged(app, event)
            value = app.PlottedVariableDropDown.Value;
            % update sliders
            UpdateSliders(app)
            % update figure
            UpdateFigure(app);
       
        end

        % Value changed function: TimeIndexSlider
        function TimeIndexSliderValueChanged(app, event)
            % link edit field to slider value
            value = app.TimeIndexSlider.Value;
            app.TimeIndexSlider.Value = round(value);
            app.TimeIndexEditField.Value = round(value);

            % update figure
            UpdateFigure(app);
        end

        % Value changed function: TimeIndexEditField
        function TimeIndexEditFieldValueChanged(app, event)
            % link slider value to edit field
            value = app.TimeIndexEditField.Value;
            if value >= min(app.TimeIndexSlider.Limits) && ...
                    value <= max(app.TimeIndexSlider.Limits)
                app.TimeIndexSlider.Value = round(value);

                % update figure
                UpdateFigure(app);
            else
                app.TimeIndexEditField.Value = 1;
            end
        end

        % Value changed function: SimulationLevelSlider
        function SimulationLevelSliderValueChanged(app, event)
            %link edit field to slider value
            value = app.SimulationLevelSlider.Value;
            app.SimulationLevelSlider.Value = round(value);
            app.SimulationLevelEditField.Value = round(value);

            % update figure
            UpdateFigure(app);
        end

        % Value changed function: SimulationLevelEditField
        function SimulationLevelEditFieldValueChanged(app, event)
            value = app.SimulationLevelEditField.Value;
            if value >= min(app.SimulationLevelSlider.Limits) && ...
                    value <= max(app.SimulationLevelSlider.Limits)
                app.SimulationLevelSlider.Value = round(value);

                % update figure
                UpdateFigure(app);
            else
                app.SimulationLevelEditField.Value = 1;
            end
                
        end

        % Button pushed function: PlotRouteButton
        function PlotRouteButtonPushed(app, event)
            % update table if new marks are added to map
            if ~isempty({app.UIAxes.Children.Children}) 
                % create new row if none are available
                if isempty(app.UITable.Data)
                    app.UITable.Data(end+1,:) = [NaN, NaN, NaN, NaN, NaN, NaN];
                end        
                
                % flip order of datatips to insert into table
                
                datatips = fliplr({app.UIAxes.Children.Children});

                % scan through datatips (some situations where they are put
                % into single cell
                COUNTER = 1;
                for iData = 1:numel(datatips)
                    if numel(datatips{iData})>1
                        datatips{iData} = flipud(datatips{iData});
                        for jData = 1:numel(datatips{iData})
                            DATATIPSTEMP{COUNTER} = datatips{iData}(jData);
                            COUNTER = COUNTER +1;
                        end
                    else
                        DATATIPSTEMP{COUNTER} = datatips{iData};
                        COUNTER = COUNTER + 1;
                    end
                end
                datatips = DATATIPSTEMP;
                

                for iData = 1:numel(datatips)

                    if strcmp(class(datatips{iData}), 'matlab.graphics.datatip.DataTip')
                        if nnz(isnan(app.UITable.Data(end, 1:2)))==2
                            %first entry initializes start of leg
                    
                            app.UITable.Data(end,1:2) = [datatips{iData}.X, datatips{iData}.Y];
                        elseif nnz(isnan(app.UITable.Data(end, 3:4)))==2
                            % if a first entry already exists, add to end of
                            % leg
                            app.UITable.Data(end,3:4) = [datatips{iData}.X, datatips{iData}.Y];
                            app.UpdateTable()
                        else
                            % create new leg and repeat above processes
                            app.UITable.Data(end+1,:) = [NaN, NaN, NaN, NaN, NaN, NaN];
                        end
                    end
                end


                clear app.UIAxes.Children.Children
            else
                %add a blank row for each click when no datatips are avail
                
                app.UITable.Data(end+1,:) = [NaN, NaN, NaN, NaN, NaN, NaN];
                
            end

            % draw flight path from table
            UpdateFlightPath(app)


        end

        % Value changed function: FileTimePatternEditField
        function FileTimePatternEditFieldValueChanged(app, event)
            value = app.FileTimePatternEditField.Value;
            %% try to guess filenaming scheme (if defaults aren't written)
            if isempty(value)
                datetimeChars = 'yyMMddHHmmss';
                datetimeChars = fliplr(datetimeChars);
                COUNTER = 1;
                timePatternGuess = fliplr(app.FileList(1).name);
                
                for iChar = 1:numel(timePatternGuess)
                    if ~isnan(str2double(timePatternGuess(iChar)))
                        timePatternGuess(iChar) = datetimeChars(COUNTER);
                        COUNTER = COUNTER + 1;
                    else
                        timePatternGuess(iChar) = '*';
                    end
                end
                timePatternGuess = fliplr(timePatternGuess);
                app.FileTimePatternEditField.Value = timePatternGuess;
            end

            %% update filetimes variable
            datetimeChars = 'yMdHms';
            findNumber = @(jChar, iFile) str2num(app.FileList(iFile).name(app.FileTimePatternEditField.Value == datetimeChars(jChar)));
            
            for iFile = 1:numel(app.FileList)
                DT = 0;
                for jChar = 1:numel(datetimeChars)
                    
                    DURATION = findNumber(jChar,iFile);
                    if ~isempty(DURATION)
                        DT = DT + findNumber(jChar,iFile);
                    end
                end
                app.FileTimes(iFile) = DT;
            end

            %% Update the start time slider
            app.StartTimeSlider.Limits = [min(app.FileTimes), max(app.FileTimes)];
            app.StartTimeSlider.MinorTicks = app.FileTimes;
            
        end

        % Cell edit callback: UITable
        function UITableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;

            %% try to complete flight calculation for each row

            app.UpdateTable()
            UpdateFlightPath(app)
        end

        % Value changed function: SimulationResolutionEditField
        function SimulationResolutionEditFieldValueChanged(app, event)
            value = app.SimulationResolutionEditField.Value;
            app.UpdateTable()
        end

        % Value changed function: StartTimeSlider
        function StartTimeSliderValueChanged(app, event)
            value = app.StartTimeSlider.Value;
            %% link StartTimeEditField
            [~, ind] = min(abs(value(1) - app.FileTimes));
            [~, ind2] = min(abs(value(2) - app.FileTimes));
            app.StartTimeSlider.Value = [app.FileTimes(ind), app.FileTimes(ind2)];
            app.StartTimeEditField.Value = app.FileTimes(ind);
            
            
        end

        % Close request function: UIFigure
        function UIFigureCloseRequest(app, event)
            delete("aospregui.log", 'file')
            writelines(...
                {...
                    app.FileSearchPatternEditField.Value, ...
                    app.FileTimePatternEditField.Value, ...
                    num2str(app.SimulationResolutionEditField.Value) ...
                }, ...
                '.aospregui.log'...
            );
            delete(app)
        end

        % Menu selected function: ClearSelection
        function ClearSelectionSelected(app, event)
            %clear selection
            
            for iCell = 1:size(app.UITable.Selection,1)
                app.UITable.Data(...
                    app.UITable.Selection(iCell, 1), ...
                    app.UITable.Selection(iCell, 2) ...
                ) = NaN;
            end

        end

        % Menu selected function: AddnewLegMenu
        function AddnewLegMenuSelected(app, event)
            % add a new leg to the table
            app.UITable.Data(end+1, :) = NaN([1,6]);
        end

        % Menu selected function: LegRowMenu
        function LegRowMenuSelected(app, event)
            
            app.UITable.Data(...
                    app.UITable.Selection(:,1),:) = [] ...
            ;
        end

        % Menu selected function: RefreshSelectionMenu
        function RefreshSelectionMenuSelected(app, event)
            %% refresh entire row if start is selected
            % delete pairs of cells
            
            pairs = {[],[],  [3,4], [3,4], [5,6], [5,6]};
            for iCell = 1:size(app.UITable.Selection,1)
                app.UITable.Data(...
                    app.UITable.Selection(iCell, 1), ...
                    pairs{app.UITable.Selection(iCell, 2)} ...
                ) = NaN;
            end

            %% update table after removing data
            app.UpdateTable()
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIFigure.CloseRequestFcn = createCallbackFcn(app, @UIFigureCloseRequest, true);
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Title')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.PlotDetailsPanel = uipanel(app.UIFigure);
            app.PlotDetailsPanel.Title = 'Plot Details';
            app.TimeIndexEditField = uieditfield(app.PlotDetailsPanel, 'numeric');
            app.TimeIndexEditField.ValueChangedFcn = createCallbackFcn(app, @TimeIndexEditFieldValueChanged, true);
            app.TimeIndexEditField.Visible = 'off';
            app.TimeIndexEditField.Value = 1;
            app.TimeIndexLabel = uilabel(app.PlotDetailsPanel);
            app.TimeIndexLabel.Visible = 'off';
            app.TimeIndexLabel.Text = 'Time Index';
            app.TimeIndexSlider = uislider(app.PlotDetailsPanel);
            app.TimeIndexSlider.ValueChangedFcn = createCallbackFcn(app, @TimeIndexSliderValueChanged, true);
            app.TimeIndexSlider.Visible = 'off';
            app.SimulationLevelEditField = uieditfield(app.PlotDetailsPanel, 'numeric');
            app.SimulationLevelEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationLevelEditFieldValueChanged, true);
            app.SimulationLevelEditField.Visible = 'off';
            app.SimulationLevelEditField.Value = 1;
            app.SimLevelLabel = uilabel(app.PlotDetailsPanel);
            app.SimLevelLabel.Visible = 'off';
            app.SimLevelLabel.Text = 'Sim. Level';
            app.SimulationLevelSlider = uislider(app.PlotDetailsPanel);
            app.SimulationLevelSlider.ValueChangedFcn = createCallbackFcn(app, @SimulationLevelSliderValueChanged, true);
            app.SimulationLevelSlider.Visible = 'off';
            app.SimulationResolutionEditField = uieditfield(app.PlotDetailsPanel, 'numeric');
            app.SimulationResolutionEditField.ValueChangedFcn = createCallbackFcn(app, @SimulationResolutionEditFieldValueChanged, true);
            app.SimulationResolutionEditField.Visible = 'off';
            app.SimulationResolutionEditField.Value = 1;
            app.SimResolutionLabel = uilabel(app.PlotDetailsPanel);
            app.SimResolutionLabel.Visible = 'off';
            app.SimResolutionLabel.Text = 'Sim. Resolution (m)';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.SimulationDetailsTab = uitab(app.TabGroup);
            app.SimulationDetailsTab.Title = 'Simulation Details';
            app.FileSearchPatternEditFieldLabel = uilabel(app.SimulationDetailsTab);
            app.FileSearchPatternEditFieldLabel.Text = 'File Search Pattern';
            app.FileSearchPatternEditField = uieditfield(app.SimulationDetailsTab, 'text');
            app.FileSearchPatternEditField.ValueChangedFcn = createCallbackFcn(app, @FileSearchPatternEditFieldValueChanged, true);
            app.FileSearchPatternEditField.Tooltip = {''};
            app.FileSearchPatternEditField.Placeholder = 'path/to/simulation/directory';
            app.FileSearchFeedback = uilabel(app.SimulationDetailsTab);
            app.FileSearchFeedback.Text = '';
            app.PlottedVariableDropDownLabel = uilabel(app.SimulationDetailsTab);
            app.PlottedVariableDropDownLabel.Visible = 'off';
            app.PlottedVariableDropDownLabel.Text = 'Plotted Variable';
            app.PlottedVariableDropDown = uidropdown(app.SimulationDetailsTab);
            app.PlottedVariableDropDown.Items = {'Set Search Pattern'};
            app.PlottedVariableDropDown.ValueChangedFcn = createCallbackFcn(app, @PlottedVariableDropDownValueChanged, true);
            app.PlottedVariableDropDown.Visible = 'off';
            app.PlottedVariableDropDown.Value = 'Set Search Pattern';
            app.FileTimePatternEditFieldLabel = uilabel(app.SimulationDetailsTab);
            app.FileTimePatternEditFieldLabel.Text = 'File Time Pattern';
            app.FileTimePatternEditField = uieditfield(app.SimulationDetailsTab, 'text');
            app.FileTimePatternEditField.ValueChangedFcn = createCallbackFcn(app, @FileTimePatternEditFieldValueChanged, true);
            app.FileTimePatternEditField.Tooltip = {'Enter search pattern for output files. I''ve done my best to guess, correct if necessary. Use the following format: yyMMddHHmmss.'; 'e.g.: ''????????dHHmmss????'''; 'or'; '''?????????sssss????*'''};
            app.AOSPREDetailsTab = uitab(app.TabGroup);
            app.AOSPREDetailsTab.Title = 'AOSPRE Details';
            app.StartTimeSlider = uislider(app.AOSPREDetailsTab, 'range');
            app.StartTimeSlider.ValueChangedFcn = createCallbackFcn(app, @StartTimeSliderValueChanged, true);
            app.PlotRouteButton = uibutton(app.AOSPREDetailsTab, 'push');
            app.PlotRouteButton.ButtonPushedFcn = createCallbackFcn(app, @PlotRouteButtonPushed, true);
            app.PlotRouteButton.Text = 'Plot Route';
            app.UITable = uitable(app.AOSPREDetailsTab);
            app.UITable.ColumnName = {'Start X'; 'Start Y'; 'End X'; 'End Y'; 'Leg Time'; 'Heading'};
            app.UITable.RowName = {};
            app.UITable.ColumnEditable = true;
            app.UITable.CellEditCallback = createCallbackFcn(app, @UITableCellEdit, true);
            app.StartTimeEditFieldLabel = uilabel(app.AOSPREDetailsTab);
            app.StartTimeEditFieldLabel.Text = 'Start Time';
            app.StartTimeEditField = uieditfield(app.AOSPREDetailsTab, 'numeric');
            app.StartTimeEditField.ValueChangedFcn = createCallbackFcn(app, @StartTimeEditFieldValueChanged, true);
            app.AircraftVelmsEditFieldLabel = uilabel(app.AOSPREDetailsTab);
            app.AircraftVelmsEditFieldLabel.Text = 'Aircraft Vel. (m/s)';
            app.AircraftVelmsEditField = uieditfield(app.AOSPREDetailsTab, 'numeric');
            app.AircraftVelmsEditField.Value = 120;
            app.PanelandScanDetails = uipanel(app.AOSPREDetailsTab);
            app.PanelandScanDetails.Title = 'Panel and Scan Details';
            app.GridLayout = uigridlayout(app.PanelandScanDetails);
            app.Tree = uitree(app.GridLayout, 'checkbox');
            app.LHSPortPanelNode = uitreenode(app.Tree);
            app.LHSPortPanelNode.Text = 'LHS/Port Panel';
            app.LHSRHI = uitreenode(app.LHSPortPanelNode);
            app.LHSRHI.Text = 'RHI';
            app.LHSPPI = uitreenode(app.LHSPortPanelNode);
            app.LHSPPI.Text = 'PPI/Sector Scan';
            app.LHSFore = uitreenode(app.LHSPortPanelNode);
            app.LHSFore.Text = 'Fore Scan';
            app.AftScanNode = uitreenode(app.LHSPortPanelNode);
            app.AftScanNode.Text = 'Aft Scan';
            app.RHSStarboardPanelNode = uitreenode(app.Tree);
            app.RHSStarboardPanelNode.Text = 'RHS/Starboard Panel';
            app.RHSRHI = uitreenode(app.RHSStarboardPanelNode);
            app.RHSRHI.Text = 'RHI';
            app.RHSPPI = uitreenode(app.RHSStarboardPanelNode);
            app.RHSPPI.Text = 'PPI/Sector Scan';
            app.RHSFore = uitreenode(app.RHSStarboardPanelNode);
            app.RHSFore.Text = 'Dual-Doppler/Fore+Aft';
            app.TopPanelNode = uitreenode(app.Tree);
            app.TopPanelNode.Text = 'Top Panel';
            app.TOPRHI = uitreenode(app.TopPanelNode);
            app.TOPRHI.Text = 'RHI';
            app.TOPPPI = uitreenode(app.TopPanelNode);
            app.TOPPPI.Text = 'PPI';
            app.TOPFore = uitreenode(app.TopPanelNode);
            app.TOPFore.Text = 'Dual-Doppler/Fore+Aft';
            app.BottomPanelNode = uitreenode(app.Tree);
            app.BottomPanelNode.Text = 'Bottom Panel';
            app.BOTRHI = uitreenode(app.BottomPanelNode);
            app.BOTRHI.Text = 'RHI';
            app.BOTPPI = uitreenode(app.BottomPanelNode);
            app.BOTPPI.Text = 'PPI';
            app.BOTFore = uitreenode(app.BottomPanelNode);
            app.BOTFore.Text = 'Dual-Doppler/Fore+Aft';
            app.angularresolutionEditFieldLabel = uilabel(app.GridLayout);
            app.angularresolutionEditFieldLabel.Text = 'angular resolution';
            app.angularresolutionEditField = uieditfield(app.GridLayout, 'text');
            app.angularresolutionEditField.Value = '1.5';
            app.ForeEditField_2Label = uilabel(app.GridLayout);
            app.ForeEditField_2Label.Text = 'Fore';
            app.ForeEditField_2 = uieditfield(app.GridLayout, 'text');
            app.ForeEditField_2.Value = '5';
            app.AftEditFieldLabel = uilabel(app.GridLayout);
            app.AftEditFieldLabel.Text = 'Aft';
            app.AftEditField = uieditfield(app.GridLayout, 'text');
            app.AftEditField.Value = '35';
            app.MaximumTiltRotEditFieldLabel = uilabel(app.GridLayout);
            app.MaximumTiltRotEditFieldLabel.Text = 'Maximum Tilt/Rot';
            app.MaximumTiltRotEditField = uieditfield(app.GridLayout, 'text');
            app.MaximumTiltRotEditField.Value = '45';
            app.ContextMenu = uicontextmenu(app.UIFigure);
            app.ClearMenu = uimenu(app.ContextMenu);
            app.ClearMenu.Text = 'Clear';
            app.ClearSelection = uimenu(app.ClearMenu);
            app.ClearSelection.MenuSelectedFcn = createCallbackFcn(app, @ClearSelectionSelected, true);
            app.ClearSelection.Text = 'Selection';
            app.LegRowMenu = uimenu(app.ClearMenu);
            app.LegRowMenu.MenuSelectedFcn = createCallbackFcn(app, @LegRowMenuSelected, true);
            app.LegRowMenu.Text = 'Leg/Row';
            app.RefreshMenu = uimenu(app.ContextMenu);
            app.RefreshMenu.Text = 'Refresh';
            app.RefreshSelectionMenu = uimenu(app.RefreshMenu);
            app.RefreshSelectionMenu.MenuSelectedFcn = createCallbackFcn(app, @RefreshSelectionMenuSelected, true);
            app.RefreshSelectionMenu.Text = 'Selection';
            app.AddnewLegMenu = uimenu(app.ContextMenu);
            app.AddnewLegMenu.MenuSelectedFcn = createCallbackFcn(app, @AddnewLegMenuSelected, true);
            app.AddnewLegMenu.Text = 'Add new Leg';
            % Assign app.ContextMenu
            app.UITable.ContextMenu = app.ContextMenu;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = aospregui

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end